classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImpedancesoffButton     matlab.ui.control.Button
        StopAcquisitionButton   matlab.ui.control.Button
        ImpedancesonButton      matlab.ui.control.Button
        StartAcquisitionButton  matlab.ui.control.Button
        PauseAcquisitionButton  matlab.ui.control.Button
        MakeConnectionButton    matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Sensors = [
                0.22, 0.7;   % F7
                0.37, 0.85;  % Fp1
                0.63, 0.85;  % Fp2
                0.78, 0.7;   % F8
                0.35, 0.68;  % F3
                0.5, 0.65;   % Fz
                0.65, 0.68;  % F4
                0.33, 0.5;   % C3
                0.5, 0.5;    % Cz
                0.78, 0.3;   % P8
                0.22, 0.3;   % P7
                0.5, 0.35;   % Pz
                0.65, 0.32;  % P4
                0.2, 0.5;    % T7
                0.35, 0.32;  % P3
                0.37, 0.15;  % O1
                0.63, 0.15;  % O2
                0.67, 0.5;   % C4
                0.8, 0.5;    % T8
                0.08, 0.5;   % A1
                0.92, 0.5    % A2
                ];
    end

    properties (Access = private)
        EDAMP
    end 

    methods (Access = private)
        
        function plotSignalData (app)
            YP = [];
            plot(app.UIAxes, YP);
            xlim(app.UIAxes, [0 1500]); 

            variables={'f7','fp1','fp2','f8','f3','fz','f4','c3','cz','p8','p7','pz','p4','t3','p3','o1','o2','c4','t4','a2','ac1','ac2','ac3'};
            D = [0.01, 0.02, 0.03, 0.04, 0.05, 0.06, 0.07, 0.08, 0.09, 0.1, 0.11, 0.12, 0.13, 0.14, 0.15, 0.16, 0.17, 0.18, 0.19, 0.2, 0.21, 0.22, 0.23];
            %D = 0.0005:0.0005:0.0115;
            D = D';

            xline(app.UIAxes, 500, '--');
            xline(app.UIAxes, 1000, '--');
            
            while app.EDAMP.WhileRunning && ~app.EDAMP.setPause
                app.EDAMP.isRunning = true;
                y_f = app.EDAMP.frameAcquisition();

                tic
                this = y_f + D;
                YP = [YP, this];

                if size(YP, 2) > 1500
                    YP = YP(:, end-1500:end);
                end
                
                pause(0.3);
                plot(app.UIAxes, YP');
                yticks(app.UIAxes, D + 0.00015);
                yticklabels(app.UIAxes, variables);
                xticks(app.UIAxes, linspace(0, 1500, 4));
                xticklabels(app.UIAxes, 0:4);
                xline(app.UIAxes, 500, '--');
                xline(app.UIAxes, 1000, '--');

                if ~app.EDAMP.WhileRunning || app.EDAMP.setPause
                    app.EDAMP.WhileRunning = false;
                    app.EDAMP.setPause = true;
                    disp("Frame acquisition paused");
                    break;
                end
                toc
                pause(0.1);
            end
        end

        function impedanceData (app)
            cla(app.UIAxes2);
            
            colors = zeros(size(app.Sensors, 1), 3); 

            for i = 1:size(app.Sensors, 1)
                if i <= numel(app.EDAMP.ImpedanceValues) && app.EDAMP.ImpedanceValues(i) < 2500
                    colors(i, :) = [0, 0.5, 0];  % Dark green
                elseif i <= numel(app.EDAMP.ImpedanceValues) && app.EDAMP.ImpedanceValues(i) > 5000
                    colors(i, :) = [1, 0, 0];  % Red
                else
                    colors(i, :) = [0.5, 0.8, 0.5];  % Light green
                end
            end

            scatter(app.UIAxes2, app.Sensors(:, 1), app.Sensors(:, 2), 200, colors, 'filled');

            xlabel(app.UIAxes2, ' ');
            ylabel(app.UIAxes2, 'Back to Front');
            title(app.UIAxes2, "Sensor's Status");

            xlim(app.UIAxes2, [0 1]);
            ylim(app.UIAxes2, [0 1]);
        end

        function clearAxis (app)
            cla(app.UIAxes2);
            colors = [0.5, 0.5, 0.5];
            scatter(app.UIAxes2, app.Sensors(:, 1), app.Sensors(:, 2), 200, colors, 'filled');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MakeConnectionButton
        function MakeConnectionButtonPushed(app, event)
            if isempty(app.EDAMP) || ~isa(app.EDAMP, 'MyClass5') || ~app.EDAMP.portInitialized
                app.EDAMP = EDAM("COM3", app);
            end

            set(app.StartAcquisitionButton, 'Enable', 'on')
            set(app.MakeConnectionButton, 'Enable', 'off')
        end

        % Button pushed function: PauseAcquisitionButton
        function PauseAcquisitionButtonPushed(app, event)
            app.EDAMP.isRunning = false;
            app.EDAMP.WhileRunning = false;
            disp('Paused');
            app.EDAMP.pauseAcquisition();

            set(app.StopAcquisitionButton, 'Enable', 'on')
            set(app.PauseAcquisitionButton, 'Enable', 'off')
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            set(app.PauseAcquisitionButton, 'Enable', 'on')
            set(app.ImpedancesoffButton, 'Enable', 'on')
            set(app.StartAcquisitionButton, 'Enable', 'off')

            if ~app.EDAMP.WhileRunning
                app.EDAMP.WhileRunning = true;
                app.EDAMP.setPause = false;
                app.plotSignalData();
            else
                disp('Frame acquisition is already running.');
            end
        end

        % Button pushed function: ImpedancesonButton
        function ImpedancesonButtonPushed(app, event)
            app.EDAMP.ImpOn();

            set(app.ImpedancesoffButton, 'Enable', 'on')
            set(app.ImpedancesonButton, 'Enable', 'off')
        end

        % Button pushed function: StopAcquisitionButton
        function StopAcquisitionButtonPushed(app, event)
            app.EDAMP.isRunning = false;
            app.EDAMP.WhileRunning = false;
            disp('Terminated Connection');
            app.EDAMP.delete();
            app.EDAMP = [];

            set(app.MakeConnectionButton, 'Enable', 'on')
            set(app.StartAcquisitionButton, 'Enable', 'off')
            set(app.PauseAcquisitionButton, 'Enable', 'off')
            set(app.ImpedancesoffButton, 'Enable', 'off')
            set(app.ImpedancesonButton, 'Enable', 'off')
            set(app.StopAcquisitionButton, 'Enable', 'off')
        end

        % Button pushed function: ImpedancesoffButton
        function ImpedancesoffButtonPushed(app, event)
            app.EDAMP.ImpOff();
            app.clearAxis();

            set(app.ImpedancesonButton, 'Enable', 'on')
            set(app.ImpedancesoffButton, 'Enable', 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (3 seconds)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Impedences')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.MakeConnectionButton = uibutton(app.UIFigure, 'push');
            app.MakeConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @MakeConnectionButtonPushed, true);
            app.MakeConnectionButton.Text = 'Make Connection';
            app.PauseAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.PauseAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAcquisitionButtonPushed, true);
            app.PauseAcquisitionButton.Enable = 'off';
            app.PauseAcquisitionButton.Text = 'Pause Acquisition';
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Enable = 'off';
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.ImpedancesonButton = uibutton(app.UIFigure, 'push');
            app.ImpedancesonButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedancesonButtonPushed, true);
            app.ImpedancesonButton.Enable = 'off';
            app.ImpedancesonButton.Text = 'Impedances on';
            app.StopAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StopAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopAcquisitionButtonPushed, true);
            app.StopAcquisitionButton.Enable = 'off';
            app.StopAcquisitionButton.Text = 'Stop Acquisition';
            app.ImpedancesoffButton = uibutton(app.UIFigure, 'push');
            app.ImpedancesoffButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedancesoffButtonPushed, true);
            app.ImpedancesoffButton.Enable = 'off';
            app.ImpedancesoffButton.Text = 'Impedances off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end