classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StartButton          matlab.ui.control.Button
        ClearButton          matlab.ui.control.Button
        PlotfirstlineButton  matlab.ui.control.Button
        GatherdataButton     matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %initialize vectors
        M = zeros(23, 10);
        y2 = zeros(23,10);
        y = zeros(23,10);
        ts = zeros(23,3);
        
    end
    %make buffer function
    methods (Access = private)
        
        function v = make_buffer(A)
            %where A is data3
            idx = find(A == 255);
            
            Fc = A(idx+1);
            
            % Create a linear index vector that selects the desired elements
            linearIdx = bsxfun(@plus, idx.', 2:70);
            
            % Convert the linear index vector to a logical mask
            mask = ismember(1:numel(A), linearIdx(:));
            TempACC = A(mask);
            
            Msb = TempACC(1,1:3:end);
            Lsb2 = TempACC(1,2:3:end);
            Lsb1 = TempACC(1,3:3:end);
            Msb = (Msb/2).*2^14;
            Lsb2 = (Lsb2/2).*2^7;
            Lsb1 = Lsb1/2;
            TempC = Msb+Lsb2+Lsb1;
            TempC = TempC*2^3;
            %convertion to mVolt
            TempC = TempC*(5/3)*(1/2^32);
        
            % Extract every 23 elements and reshape them into a matrix
            buff = reshape(TempC, 23, []);
        
            % extract the remaining variables from data
            ImpCheck = A(idx+71);
            VoltageCheck = A(idx+72);
            Trig1 = A(idx+73);
            Trig2 = A(idx+74);
            
            %get impedences
            %Take the greater of the two differences
            GI = [max(abs(buff(:,1) - buff(:,3))/2,abs(buff(:,2) - buff(:,4))/2)];
            GI = GI * 265000000;
            
            v = buff;
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GatherdataButton
        function GatherdataButtonPushed(app, event)
            
            timeout = 1; 
            start_time = tic; 
            while toc(start_time) <= timeout %it will run for 1 second
                tic % start timer
                NWT = read(s,750,"uint8");
            
                mb = make_buffer(app, NWT);
                
                se(:,1:10) = mb;
                se = circshift(se, [0,-10]);
                
                app.y = filter([0.85,0,0.85],[1,0,0.7],se);
                      
                app.y2 = filter([0.8,0.8],[1,0.6],app.y);
            
                app.M(:,1:10)=app.y2(:,11:20);
                app.M = circshift(app.M, [0,-10]);
                % inner loop with 1 sec interval
                if mod(floor(toc(start_time)), 1) == 0 % check if 1 sec has elapsed
                    y_filtered = highpass(app.M, 0.5, 500,Steepness=0.5,StopbandAttenuation=30);        
                end
            
                % pause for the remaining time
                pause(0.1 - toc) 
            end
        end

        % Button pushed function: PlotfirstlineButton
        function PlotfirstlineButtonPushed(app, event)
            %plot(app.UIAxes,nexttile,buff(1,1:end));x=1:0.1:10;z=sin(x);plot(app.UIAxes2, x, z);
            
            % Call test4.m using eval
            test4

            % Get the handle of the current figure
            %figHandle = evalin('base', 'figHandle');

            % Find the axes in the test4.m plot
            %test4PlotAxes = findobj(figHandle, 'Type', 'axes');

            % Transfer the children (plots, lines, etc.) to appPlot axes
            %set(test4PlotAxes.Children, 'Parent', app.UIAxes);
            % Get the handle of the plot axes from test4.m
            plotAxes = evalin('base', 'plotAxes');
        
            % Transfer the plot to myAxes
            copyobj(get(plotAxes, 'children'), app.UIAxes);
            set(app.UIAxes, 'YTick', D+0.0025, 'YTickLabel', variables);
            app.UIAxes.YAxis.TickLength = [0, 0];
            app.UIAxes.XTick = linspace(0, 100, 11);
            app.UIAxes.XTickLabel = 0:10;
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clear_file;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %myObject = MyClass();
            %myObject.frameAcquisition();
            call_that;
            %delete(app.UIAxes.Children);

            % Get the handle of the plot axes from test4.m
            %plotAxes = evalin('base', 'plotAxes');

            % Transfer the plot to myAxes in the App Designer
            %copyobj(get(plotAxes, 'children'), app.UIAxes);
            %set(app.UIAxes, 'YTick', D+0.0025, 'YTickLabel', variables);
            %app.UIAxes.YAxis.TickLength = [0, 0];
            %app.UIAxes.XTick = linspace(0, 100, 11);
            %app.UIAxes.XTickLabel = 0:10;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualiztion')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Impedences')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.GatherdataButton = uibutton(app.UIFigure, 'push');
            app.GatherdataButton.ButtonPushedFcn = createCallbackFcn(app, @GatherdataButtonPushed, true);
            app.GatherdataButton.Text = 'Gather data';
            app.PlotfirstlineButton = uibutton(app.UIFigure, 'push');
            app.PlotfirstlineButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfirstlineButtonPushed, true);
            app.PlotfirstlineButton.Text = 'Plot first line';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear ';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end