classdef appTest2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImpedancesoffButton     matlab.ui.control.Button
        StopAcquisitionButton   matlab.ui.control.Button
        ImpedancesonButton      matlab.ui.control.Button
        StartAcquisitionButton  matlab.ui.control.Button
        PauseAcquisitionButton  matlab.ui.control.Button
        MakeConnectionButton    matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        PauseButtonPressed = false;
        getImpedances = true;
    end

    properties (Access = private)
        MyClass
        %runInLoop = false;

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MakeConnectionButton
        function MakeConnectionButtonPushed(app, event)
            if isempty(app.MyClass) || ~isa(app.MyClass, 'MyClass4') || ~app.MyClass.portInitialized
                app.MyClass = MyClass4("COM3", app);
            end
            app.MyClass.isRunning = false;
        end

        % Button pushed function: PauseAcquisitionButton
        function PauseAcquisitionButtonPushed(app, event)
            %app.runInLoop = false;
            app.MyClass.isRunning = false;
            disp('Paused');
            app.MyClass.pauseAcquisition();
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            
            if ~app.MyClass.isRunning
                disp("we in baby");
                %app.runInLoop = true;
                app.MyClass.isRunning = true;
                app.PauseButtonPressed = false;
                while app.MyClass.isRunning && ~app.PauseButtonPressed
                %while app.runInLoop
                    pause(1);
                    app.MyClass.frameAcquisition();
                end
            else
                disp('Frame acquisition is already running.');
            end
        end

        % Button pushed function: ImpedancesonButton
        function ImpedancesonButtonPushed(app, event)
            app.MyClass.ImpOn();
            
        end

        % Button pushed function: StopAcquisitionButton
        function StopAcquisitionButtonPushed(app, event)
            %app.runInLoop = false;
            app.MyClass.isRunning = false;
            disp('Terminated Connection');
            app.MyClass.delete();
            app.MyClass = [];
            %app.MyClass.isRunning = false;
        end

        % Button pushed function: ImpedancesoffButton
        function ImpedancesoffButtonPushed(app, event)
            app.MyClass.ImpOff();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Impedences')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.MakeConnectionButton = uibutton(app.UIFigure, 'push');
            app.MakeConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @MakeConnectionButtonPushed, true);
            app.MakeConnectionButton.Text = 'Make Connection';
            app.PauseAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.PauseAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAcquisitionButtonPushed, true);
            app.PauseAcquisitionButton.Text = 'Pause Acquisition';
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.ImpedancesonButton = uibutton(app.UIFigure, 'push');
            app.ImpedancesonButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedancesonButtonPushed, true);
            app.ImpedancesonButton.Text = 'Impedances on';
            app.StopAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StopAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopAcquisitionButtonPushed, true);
            app.StopAcquisitionButton.Text = 'Stop Acquisition';
            app.ImpedancesoffButton = uibutton(app.UIFigure, 'push');
            app.ImpedancesoffButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedancesoffButtonPushed, true);
            app.ImpedancesoffButton.Text = 'Impedances off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end