classdef UI_storedData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImpedancesoffButton     matlab.ui.control.Button
        StopAcquisitionButton   matlab.ui.control.Button
        ImpedancesonButton      matlab.ui.control.Button
        StartAcquisitionButton  matlab.ui.control.Button
        PauseAcquisitionButton  matlab.ui.control.Button
        MakeConnectionButton    matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Sensors = [
                0.22, 0.7;   % F7
                0.37, 0.85;  % Fp1
                0.63, 0.85;  % Fp2
                0.78, 0.7;   % F8
                0.35, 0.68;  % F3
                0.5, 0.65;   % Fz
                0.65, 0.68;  % F4
                0.33, 0.5;   % C3
                0.5, 0.5;    % Cz
                0.78, 0.3;   % P8
                0.22, 0.3;   % P7
                0.5, 0.35;   % Pz
                0.65, 0.32;  % P4
                0.2, 0.5;    % T7
                0.35, 0.32;  % P3
                0.37, 0.15;  % O1
                0.63, 0.15;  % O2
                0.67, 0.5;   % C4
                0.8, 0.5;    % T8
                0.08, 0.5;   % A1
                0.92, 0.5    % A2
                ];
        output_array
        saved_flag = false;
        step=1;
        displayBuffer = zeros(23,1500);
        distancesBetweenChannels=[];
        index = 1;
        newBuffer
        zf = [];
        % window_size = 10;  
        % overlap = 0.25;
    end

    properties (Access = private)
        EDAMP
    end
    methods (Access = private)

        function plotSignalData(app)
            plot(app.UIAxes, app.displayBuffer);
            xlim(app.UIAxes, [0 1500]);

            variables={'f7','fp1','fp2','f8','f3','fz','f4','c3','cz','p8','p7','pz','p4','t3','p3','o1','o2','c4','t4','a2','ac1','ac2','ac3'};

            xline(app.UIAxes, 500, '--');
            xline(app.UIAxes, 1000, '--');

            while app.EDAMP.WhileRunning && ~app.EDAMP.setPause
                tic
                app.index = app.index + 500;
                if (app.index >= app.EDAMP.end_frame)
                    disp("End of procedure")
                    beep
                    break
                end
                app.EDAMP.loadData();
                app.output_array = app.EDAMP.constructBuffer();

                preprocessedBuffer = zeros(23, 500);
                for i = 1:23
                    sensorData = app.output_array(i, :);
                    % 
                    detrended_data = detrend(sensorData);

                    minVal = min(detrended_data);
                    maxVal = max(detrended_data);
                    plotRange = maxVal - minVal;

                    rescaledData = (detrended_data - minVal) / plotRange *2 - 1;

                    preprocessedBuffer(i, :) = rescaledData;
                end

                % polynomial_order = 5;  % Adjust order as needed
                % frame_length = 13;     % Adjust frame length as needed
                % % Smooth data for each channel
                % smoothed_data = zeros(size(preprocessedBuffer));
                % for channel_index = 1:size(preprocessedBuffer, 2)
                %     smoothed_data(:, channel_index) = sgolayfilt(preprocessedBuffer(:, channel_index), polynomial_order, frame_length);
                % end
               
                % new_data = app.output_array;
                % output = zeros(23, 500);
                % 
                % for channel_index = 1:size(new_data, 1)
                %     channel_data = new_data(channel_index, :);
                % 
                %     % Apply windowed baseline correction and standardization
                %     standardized_data = zeros(size(channel_data));
                %     for window_start = 1:app.window_size:length(channel_data) - app.window_size + 1
                %         window_end = min(window_start + app.window_size - 1, length(channel_data));
                %         window_data = channel_data(window_start:window_end);
                % 
                %         % Baseline correction (using mean)
                %         baseline = mean(window_data(1:floor(app.overlap*app.window_size)));
                %         corrected_data = window_data - baseline;
                % 
                %         % Standardization (divide by standard deviation)
                %         std_dev = std(corrected_data);
                %         standardized_data(window_start:window_end) = corrected_data / std_dev;
                %     end
                %     output(channel_index, :) = standardized_data;
                % end

                fs = 500;
                cutFreq = 45; 
                filterOrder = 3;
                passband_ripple = 0.5;
                Wn = (2 * cutFreq) / fs; 
                [bL, aL] = cheby1(filterOrder, passband_ripple, Wn, 'low');

                sectionSize = 500; 
                out = zeros(size(preprocessedBuffer));               

                for i = 0:size(preprocessedBuffer, 2) / sectionSize - 1
                    range = i * sectionSize + 1 : (i + 1) * sectionSize; 
                    in = preprocessedBuffer(:, range);
                    [out(:, range), app.zf] = filter(bL, aL, in, app.zf, 2);
                end

                output = out;

                if ~app.saved_flag
                    app.step = max(output(:));
                    app.saved_flag = true;
                    app.distancesBetweenChannels = 0:app.step:app.step*22;
                    app.distancesBetweenChannels = app.distancesBetweenChannels';
                end

                app.newBuffer = output + app.distancesBetweenChannels;
                app.displayBuffer(:, 1:500) = app.newBuffer;
                app.displayBuffer = circshift(app.displayBuffer, [0, -500]);

                pause(0.8);
                plot(app.UIAxes, app.displayBuffer');
                yticks(app.UIAxes, app.distancesBetweenChannels);
                yticklabels(app.UIAxes, variables);
                xticks(app.UIAxes, linspace(0, 1500, 4));
                xticklabels(app.UIAxes, 0:4);
                xline(app.UIAxes, 500, '--');
                xline(app.UIAxes, 1000, '--');

                if ~app.EDAMP.WhileRunning || app.EDAMP.setPause
                    app.EDAMP.WhileRunning = false;
                    app.EDAMP.setPause = true;
                    disp("Frame acquisition paused");
                    break;
                end
                toc
                pause(0.1);
            end
        end

        function plotImpedanceData (app, src, event)
            % this function checks the values of the impedances and gives
            % them the appropriate color based on their value; red for
            % missplaced sensors, dark green for perfectly placed sensors,
            % light green for good placed sensors
            cla(app.UIAxes2);

            ImpedanceValuesUI = src.ImpedanceValues;
            colors = zeros(size(app.Sensors, 1), 3);

            for i = 1:size(app.Sensors, 1)
                if i <= numel(ImpedanceValuesUI) && ImpedanceValuesUI(i) < 2500
                    colors(i, :) = [0, 0.5, 0];  % Dark green
                elseif i <= numel(ImpedanceValuesUI) && ImpedanceValuesUI(i) > 5000
                    colors(i, :) = [1, 0, 0];  % Red
                else
                    colors(i, :) = [0.5, 0.8, 0.5];  % Light green
                end
            end

            % % Define conditions
            % % condition1 = app.EDAMP.ImpedanceValues < 2500;
            % % condition2 = app.EDAMP.ImpedanceValues > 5000;
            % condition1 = ImpedanceValuesUI < 2500;
            % condition2 = ImpedanceValuesUI > 5000;
            %
            % % Set colors based on conditions
            % colors(condition1, :) = [0, 0.5, 0];  % Dark green
            % colors(condition2, :) = [1, 0, 0];    % Red
            %
            % % Default color (light green)
            % default_color = [0.5, 0.8, 0.5];
            % colors(~(condition1 | condition2), :) = default_color;

            scatter(app.UIAxes2, app.Sensors(:, 1), app.Sensors(:, 2), 200, colors, 'filled');

            xlabel(app.UIAxes2, ' ');
            ylabel(app.UIAxes2, 'Back to Front');
            title(app.UIAxes2, "Sensor's Status");

            xlim(app.UIAxes2, [0 1]);
            ylim(app.UIAxes2, [0 1]);
        end

        function clearAxis (app)
            % this function clears the plot responsible for displaying
            % the impedances
            cla(app.UIAxes2);
            colors = [0.5, 0.5, 0.5];
            scatter(app.UIAxes2, app.Sensors(:, 1), app.Sensors(:, 2), 200, colors, 'filled');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MakeConnectionButton
        function MakeConnectionButtonPushed(app, event)
            % if isempty(app.EDAMP) || ~isa(app.EDAMP, 'MyClass5') || ~app.EDAMP.portInitialized
            %     app.EDAMP = MyClass5('data5.mat');
            % end
            % 
            % set(app.StartAcquisitionButton, 'Enable', 'on')
            % set(app.MakeConnectionButton, 'Enable', 'off')
        end

        % Button pushed function: PauseAcquisitionButton
        function PauseAcquisitionButtonPushed(app, event)
            app.EDAMP.WhileRunning = false;
            disp('Paused');
            app.EDAMP.pauseAcquisition();

            set(app.StopAcquisitionButton, 'Enable', 'on')
            set(app.PauseAcquisitionButton, 'Enable', 'off')
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            if isempty(app.EDAMP) || ~isa(app.EDAMP, '20240217-data1') || ~app.EDAMP.portInitialized
                app.EDAMP = EDAM_storedData('20240217-data1');
                % 20231016-data3-impOFF BigData11  20240217-data3
            end

            addlistener(app.EDAMP, 'myEvent', @(src, event) plotImpedanceData(app, src, event));
            set(app.PauseAcquisitionButton, 'Enable', 'on')
            set(app.StartAcquisitionButton, 'Enable', 'off')
            set(app.ImpedancesoffButton, 'Enable', 'on')
            set(app.ImpedancesonButton, 'Enable', 'on')

            if ~app.EDAMP.WhileRunning
                app.EDAMP.WhileRunning = true;
                app.EDAMP.setPause = false;
                app.clearAxis();
                app.plotSignalData();
            else
                disp('Frame acquisition is already running.');
            end
            set(app.PauseAcquisitionButton, 'Enable', 'off')
        end

        % Button pushed function: ImpedancesonButton
        function ImpedancesonButtonPushed(app, event)
            app.EDAMP.ImpOn();
            
            set(app.ImpedancesoffButton, 'Enable', 'on')
            set(app.ImpedancesonButton, 'Enable', 'off')
        end

        % Button pushed function: StopAcquisitionButton
        function StopAcquisitionButtonPushed(app, event)
            app.EDAMP.WhileRunning = false;
            disp('Terminated Connection');
            %app.EDAMP.delete();
            %app.EDAMP = [];
            app.step = [];
            app.saved_flag = false;
            
            set(app.MakeConnectionButton, 'Enable', 'on')
            set(app.StartAcquisitionButton, 'Enable', 'off')
            set(app.PauseAcquisitionButton, 'Enable', 'off')
            set(app.ImpedancesoffButton, 'Enable', 'off')
            set(app.ImpedancesonButton, 'Enable', 'off')
            set(app.StopAcquisitionButton, 'Enable', 'off')
        end

        % Button pushed function: ImpedancesoffButton
        function ImpedancesoffButtonPushed(app, event)
            app.EDAMP.ImpOff();
            app.clearAxis();

            set(app.ImpedancesonButton, 'Enable', 'on')
            set(app.ImpedancesoffButton, 'Enable', 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (3 seconds)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Impedences')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.MakeConnectionButton = uibutton(app.UIFigure, 'push');
            app.MakeConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @MakeConnectionButtonPushed, true);
            app.MakeConnectionButton.Enable = 'off';
            app.MakeConnectionButton.Visible = 'off';
            app.MakeConnectionButton.Text = 'Make Connection';
            app.PauseAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.PauseAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAcquisitionButtonPushed, true);
            app.PauseAcquisitionButton.Enable = 'off';
            app.PauseAcquisitionButton.Text = 'Pause Acquisition';
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.ImpedancesonButton = uibutton(app.UIFigure, 'push');
            app.ImpedancesonButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedancesonButtonPushed, true);
            app.ImpedancesonButton.Enable = 'off';
            app.ImpedancesonButton.Text = 'Impedances on';
            app.StopAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StopAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopAcquisitionButtonPushed, true);
            app.StopAcquisitionButton.Enable = 'off';
            app.StopAcquisitionButton.Text = 'Stop Acquisition';
            app.ImpedancesoffButton = uibutton(app.UIFigure, 'push');
            app.ImpedancesoffButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedancesoffButtonPushed, true);
            app.ImpedancesoffButton.Enable = 'off';
            app.ImpedancesoffButton.Text = 'Impedances off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_storedData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end